classdef GloveDefectDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        NitrileGlovesLabel              matlab.ui.control.Label
        CleaningRubberGlovesLabel       matlab.ui.control.Label
        LatexGlovesLabel                matlab.ui.control.Label
        home_nitrile_glove              matlab.ui.control.Image
        home_cleaning_rubber_glove      matlab.ui.control.Image
        home_latex_glove                matlab.ui.control.Image
        SelectwhichtypeofglovethatyouwanttodetectLabel  matlab.ui.control.Label
        WhatsWrongwithyourGlovesLabel   matlab.ui.control.Label
        LatexGlovesTab                  matlab.ui.container.Tab
        BackButton_4                    matlab.ui.control.Button
        SelectwhichdefecttodetectbasedontheimagebelowLabel  matlab.ui.control.Label
        LatexGloveDefectDetectionLabel  matlab.ui.control.Label
        DetectdirtyLabel                matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        DetectcutbynailsLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        DetectholeLabel                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        LatexHoleTab                    matlab.ui.container.Tab
        ShowhistogramButton             matlab.ui.control.Button
        NumdefectsEditField             matlab.ui.control.NumericEditField
        NumdefectsEditFieldLabel        matlab.ui.control.Label
        LatexGlovesHoleDefectDetectionLabel  matlab.ui.control.Label
        OriginalImageLabel              matlab.ui.control.Label
        CurrentthresholdvalueEditField  matlab.ui.control.EditField
        CurrentthresholdvalueEditFieldLabel  matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        DefaultOptimalThresholdButton   matlab.ui.control.Button
        ShowDefectButton                matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        SelectImageButton               matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        holes_threshold_axes            matlab.ui.control.UIAxes
        LatexNailCutTab                 matlab.ui.container.Tab
        NumdefectsEditField_2           matlab.ui.control.NumericEditField
        NumdefectsEditField_2Label      matlab.ui.control.Label
        LatexGlovesNailCutDefectDetectionLabel  matlab.ui.control.Label
        SelectedTemplateLabel           matlab.ui.control.Label
        OriginalImageLabel_2            matlab.ui.control.Label
        ShowdefectButton_2              matlab.ui.control.Button
        CurrentthresholdvalueEditField_2  matlab.ui.control.EditField
        CurrentthresholdvalueEditField_2Label  matlab.ui.control.Label
        ThresholdSlider_2               matlab.ui.control.Slider
        ThresholdSlider_2Label          matlab.ui.control.Label
        DefaultOptimalThresholdButton_2  matlab.ui.control.Button
        UploadTemplateButton            matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        BackButton_2                    matlab.ui.control.Button
        SelectImageButton_2             matlab.ui.control.Button
        Image_3                         matlab.ui.control.Image
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        LatexDirtyTab                   matlab.ui.container.Tab
        LatexGlovesDirtyDefectDetectionLabel  matlab.ui.control.Label
        OriginalImageLabel_3            matlab.ui.control.Label
        CurrentThresholdValueEditField  matlab.ui.control.EditField
        CurrentThresholdValueEditFieldLabel  matlab.ui.control.Label
        ThresholdSlider_3               matlab.ui.control.Slider
        ThresholdSlider_3Label          matlab.ui.control.Label
        DefaultOptimalThresholdButton_3  matlab.ui.control.Button
        ShowDefectButton_2              matlab.ui.control.Button
        BackButton_3                    matlab.ui.control.Button
        SelectImageButton_3             matlab.ui.control.Button
        Image_5                         matlab.ui.control.Image
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        RubberGlovesTab                 matlab.ui.container.Tab
        DetectmissingfingerpartLabel    matlab.ui.control.Label
        DetecttearatpalmareaLabel       matlab.ui.control.Label
        DetectholeLabel_2               matlab.ui.control.Label
        rubber_missing_finger_img       matlab.ui.control.Image
        rubber_palm_tear_img            matlab.ui.control.Image
        rubber_hole_img                 matlab.ui.control.Image
        SelectwhichdefecttodetectbasedontheimagebelowLabel_2  matlab.ui.control.Label
        CleaningRubberGloveDefectDetectionLabel  matlab.ui.control.Label
        BackButton_5                    matlab.ui.control.Button
        RubberHoleTab                   matlab.ui.container.Tab
        CurrentthresholdvalueEditField_3  matlab.ui.control.EditField
        CurrentthresholdvalueEditField_3Label  matlab.ui.control.Label
        ThresholdSlider_4               matlab.ui.control.Slider
        ThresholdSlider_4Label          matlab.ui.control.Label
        OriginalImageLabel_4            matlab.ui.control.Label
        DefaultOptimalThresholdButton_4  matlab.ui.control.Button
        ShowRubberHoleDefectButton      matlab.ui.control.Button
        SelectImageButton_4             matlab.ui.control.Button
        orig_rubber_hole_img            matlab.ui.control.Image
        RubberGlovesHoleDefectDetectionLabel  matlab.ui.control.Label
        BackButton_6                    matlab.ui.control.Button
        rubber_hole_detected_axes       matlab.ui.control.UIAxes
        holes_threshold_axes_2          matlab.ui.control.UIAxes
        RubberTearTab                   matlab.ui.container.Tab
        CurrentthresholdvalueEditField_5  matlab.ui.control.EditField
        CurrentthresholdvalueEditField_5Label  matlab.ui.control.Label
        ThresholdSlider_6               matlab.ui.control.Slider
        ThresholdSlider_6Label          matlab.ui.control.Label
        OriginalImageLabel_6            matlab.ui.control.Label
        DefaultOptimalThresholdButton_6  matlab.ui.control.Button
        ShowRubberTearDefectButton      matlab.ui.control.Button
        SelectImageButton_6             matlab.ui.control.Button
        orig_rubber_tear_img            matlab.ui.control.Image
        RubberGlovesPalmTearDefectDetectionLabel  matlab.ui.control.Label
        BackButton_8                    matlab.ui.control.Button
        rubber_tear_detectedAxes        matlab.ui.control.UIAxes
        rubber_tear_thresholdAxes       matlab.ui.control.UIAxes
        RubberMissingTab                matlab.ui.container.Tab
        SelectTemplateButton            matlab.ui.control.Button
        TemplateImageLabel              matlab.ui.control.Label
        finger_template                 matlab.ui.control.Image
        ShowMissingFingerdefectButton   matlab.ui.control.Button
        DefaultOptimalThresholdButton_5  matlab.ui.control.Button
        SelectImageButton_5             matlab.ui.control.Button
        CurrentthresholdvalueEditField_4  matlab.ui.control.EditField
        CurrentthresholdvalueEditField_4Label  matlab.ui.control.Label
        ThresholdSlider_5               matlab.ui.control.Slider
        ThresholdSlider_5Label          matlab.ui.control.Label
        orig_missing_finger_img         matlab.ui.control.Image
        OriginalImageLabel_5            matlab.ui.control.Label
        RubberGlovesMissingFingerPartDefectDetectionLabel  matlab.ui.control.Label
        BackButton_7                    matlab.ui.control.Button
        missing_finger_thresholdAxes    matlab.ui.control.UIAxes
        missing_finger_detectedAxes     matlab.ui.control.UIAxes
        NitrileGlovesTab                matlab.ui.container.Tab
        DetectstainLabel                matlab.ui.control.Label
        DetectRipLabel                  matlab.ui.control.Label
        DetectholeLabel_3               matlab.ui.control.Label
        nitrile_stain_detect_image      matlab.ui.control.Image
        nitrile_tear_wrist_detect_image  matlab.ui.control.Image
        nitrile_hole_detect_image       matlab.ui.control.Image
        SelectwhichdefecttodetectbasedontheimagebelowLabel_3  matlab.ui.control.Label
        NitrileGloveDefectDetectionLabel  matlab.ui.control.Label
        BackButton_9                    matlab.ui.control.Button
        NitrileHoleTab                  matlab.ui.container.Tab
        CurrentthresholdvalueEditField_6  matlab.ui.control.EditField
        CurrentthresholdvalueEditField_6Label  matlab.ui.control.Label
        ThresholdSlider_7               matlab.ui.control.Slider
        ThresholdSlider_7Label          matlab.ui.control.Label
        OriginalImageLabel_7            matlab.ui.control.Label
        DefaultOptimalThresholdButton_7  matlab.ui.control.Button
        ShowNitrileHoleDefectButton_2   matlab.ui.control.Button
        SelectImageButton_7             matlab.ui.control.Button
        orig_nitrile_hole_img_2         matlab.ui.control.Image
        NitrileGlovesHoleDetectionLabel  matlab.ui.control.Label
        BackButton_10                   matlab.ui.control.Button
        nitrile_hole_detected_axes_2    matlab.ui.control.UIAxes
        holes_threshold_axes_3          matlab.ui.control.UIAxes
        NitrileRipTab                   matlab.ui.container.Tab
        CurrentthresholdvalueEditField_7  matlab.ui.control.EditField
        CurrentthresholdvalueEditField_7Label  matlab.ui.control.Label
        ThresholdSlider_8               matlab.ui.control.Slider
        ThresholdSlider_8Label          matlab.ui.control.Label
        OriginalImageLabel_8            matlab.ui.control.Label
        DefaultOptimalThresholdButton_8  matlab.ui.control.Button
        ShowRipDefectButton_3           matlab.ui.control.Button
        SelectImageButton_8             matlab.ui.control.Button
        orig_nitrile_rip                matlab.ui.control.Image
        NitrileGlovesRipDetectionLabel  matlab.ui.control.Label
        BackButton_11                   matlab.ui.control.Button
        nitrile_rip_detected_axes       matlab.ui.control.UIAxes
        tear_threshold_axes             matlab.ui.control.UIAxes
        NitrileStainTab                 matlab.ui.container.Tab
        BackButton_12                   matlab.ui.control.Button
        HUEupperSlider                  matlab.ui.control.Slider
        HUEupperSliderLabel             matlab.ui.control.Label
        HUElowerSlider                  matlab.ui.control.Slider
        HUElowerSliderLabel             matlab.ui.control.Label
        OriginalImageLabel_9            matlab.ui.control.Label
        DefaultOptimalThresholdButton_9  matlab.ui.control.Button
        ShowNitrileStainDefectButton_4  matlab.ui.control.Button
        SelectImageButton_9             matlab.ui.control.Button
        orig_nitrile_stain_img          matlab.ui.control.Image
        NitrileGlovesStainDetectionLabel  matlab.ui.control.Label
        nitrile_stain_detected_axes     matlab.ui.control.UIAxes
        stain_threshold_axes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         getImageSource ;
        mask;
        default_thresh;
        getImageSourceTemplate;
        nailCut_mask;
        missingFinger_mask;
        channel1Min;
        channel1Max;
        num_defects;
        originalImage;
        showHist

    end
    
    methods (Access = public)
        
        function results = func(app,slider,currentThresholdValue, threshold)

            
            slider.Value = threshold;
    
    
            threshold_less = app.getImageSource < slider.Value;
            threshold_greater = app.getImageSource > slider.Value;
    
    
            currentThresholdValue.Value = num2str(slider.Value);
    
            % Create a binary mask based on the thresholded image
            app.mask = zeros(size(app.getImageSource));
            app.mask(threshold_less) = 1;
            app.mask(threshold_greater) = 0;
    
    
            % Set the output argument to any relevant results (if applicable)
            results = app.mask;
            
        end

        function results = func2(app, slider, currentThresholdValue, threshold, optimalThreshold)


                slider.Value = threshold;
                currentThresholdValue.Value = num2str(slider.Value);

                % Thresholding for the entire image
                binaryImage = app.getImageSource > slider.Value;
            
                % Perform morphological operations to remove small regions and fill holes
                se = strel('disk', 10); 
                binaryImage = imopen(binaryImage, se);
                binaryImage = imfill(binaryImage, 'holes');
            
                % Create a binary mask based on the processed image
                app.mask = zeros(size(app.getImageSource));
                app.mask(binaryImage) = 1;
            
                % Extract properties of connected components
                props = regionprops(app.mask, 'Area', 'PixelIdxList');
            
                % Find the component representing the hole (adjust conditions as needed)
                holeIndex = find([props.Area] > 100 & [props.Area] < 1000); 
            
                % Create a new binary mask highlighting only the hole if a valid component is found
                if ~isempty(holeIndex)
                    app.mask(:) = 0;
                    app.mask(props(holeIndex).PixelIdxList) = 1;
                end
            
                % Set the output argument to any relevant results (if applicable)
                results = app.mask;
        end


        function [BW,maskedRGBImage] = colorSegment(app, RGB, channel1Min, channel1Max)            

            % Convert RGB image to chosen color space
            I = rgb2hsv(RGB);
            
            
            % Define thresholds for channel 2 based on histogram settings
            channel2Min = 0.000;
            channel2Max = 0.485;
            
            % Define thresholds for channel 3 based on histogram settings
            channel3Min = 0.000;
            channel3Max = 0.494;
            
            % Create mask based on chosen histogram thresholds
            sliderBW = (I(:,:,1) >= channel1Min ) & (I(:,:,1) <= channel1Max) & ...
                (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
            BW = sliderBW;

            BW = bwareaopen(BW, 200);

            % Initialize output masked image based on input image.
            maskedRGBImage = RGB;
            
            % Set background pixels where BW is false to zero.
            maskedRGBImage(repmat(~BW,[1 1 3])) = 0;

        
        end

        
        function results = readImage(app)
             [filename, pathname] = uigetfile({'*.jpg'; '*.png';'*.bmp'},'Choose glove image');
        
             % If the user didn't cancel, load the image file and display it.
            if filename ~= 0 
                % concatenate the path and image name
                fullFilename = fullfile(pathname, filename);
                results=fullFilename;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.TabGroup.SelectedTab = app.LatexHoleTab;

        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            fileName =readImage(app);

            selectedImage = imread(fileName);
            app.Image_2.ImageSource= selectedImage;
            app.getImageSource=selectedImage;
            
            app.getImageSource= im2gray(app.getImageSource);
            app.getImageSource = im2double(app.getImageSource);
          
            
           %apply filter
             app.getImageSource=medfilt2(app.getImageSource); 
             app.showHist=app.getImageSource ;
            % Display the image.
            app.Image.ImageSource= selectedImage;

            app.default_thresh=graythresh(app.getImageSource);
            app.mask=func(app,app.ThresholdSlider, app.CurrentthresholdvalueEditField ,app.default_thresh);
            imshow(app.mask,'Parent',app.holes_threshold_axes);
        
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
                   
            value = app.ThresholdSlider.Value;

             app.mask=func(app,app.ThresholdSlider, app.CurrentthresholdvalueEditField ,value);
            
            
            imshow(app.mask,'Parent',app.holes_threshold_axes);
          
        end

        % Button pushed function: DefaultOptimalThresholdButton
        function DefaultOptimalThresholdButtonPushed(app, event)
              app.mask=func(app,app.ThresholdSlider, app.CurrentthresholdvalueEditField ,app.default_thresh);
            
            
            imshow(app.mask,'Parent',app.holes_threshold_axes);
        end

        % Button pushed function: ShowDefectButton
        function ShowDefectButtonPushed(app, event)
            app.num_defects=0;
            element1=strel('disk',5);
            app.mask=imdilate(app.mask,element1);
            
            app.mask = bwareaopen(app.mask, 100);
            cc=bwconncomp(app.mask,4);
            props = regionprops(cc, 'all');
           
            
         imshow(app.getImageSource, 'Parent', app.UIAxes2);
          hold(app.UIAxes2, 'on');
         
          for i = 1:length(props)
    
            if(props(i).Circularity>0.5 || props(i).Eccentricity<0.6)
                 app.num_defects= app.num_defects+1;
                 box = props(i).BoundingBox;
                 rectangle(app.UIAxes2, 'Position', box, 'EdgeColor', 'red', 'LineWidth', 2);
                 text(app.UIAxes2,props(i).Centroid(1)+10, props(i).Centroid(2)+20, 'Hole', 'Color', ...
                    'r', 'FontSize', 12, 'HorizontalAlignment', 'center')
            end
         end
          hold(app.UIAxes2, 'off');
          app.NumdefectsEditField.Value=app.num_defects;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
              app.TabGroup.SelectedTab = app.LatexGlovesTab;

        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
               app.TabGroup.SelectedTab = app.LatexNailCutTab;
        end

        % Button pushed function: SelectImageButton_2
        function SelectImageButton_2Pushed(app, event)
            
                fileName =readImage(app);

                selectedImage = imread(fileName);
                  app.originalImage=selectedImage;
                app.Image_3.ImageSource= selectedImage;
                app.getImageSource=selectedImage;
                app.getImageSource= im2gray(app.getImageSource);
                app.getImageSource = im2double(app.getImageSource);
                app.getImageSource=imadjust(app.getImageSource,[0,0.4], [0,1]);
                app.showHist= app.getImageSource;
                app.default_thresh=graythresh(app.getImageSource); 
                app.mask=func(app,app.ThresholdSlider_2, app.CurrentthresholdvalueEditField_2, ...
                    app.default_thresh);
                imshow(app.mask,'Parent',app.UIAxes3);
            
        end

        % Button pushed function: DefaultOptimalThresholdButton_2
        function DefaultOptimalThresholdButton_2Pushed(app, event)
             app.mask=func(app,app.ThresholdSlider_2, app.CurrentthresholdvalueEditField_2 ,app.default_thresh);
             imshow(app.mask,'Parent',app.UIAxes3);
        end

        % Value changed function: ThresholdSlider_2
        function ThresholdSlider_2ValueChanged(app, event)
            value = app.ThresholdSlider_2.Value;
              app.mask=func(app,app.ThresholdSlider_2, app.CurrentthresholdvalueEditField_2,value);
            
            imshow(app.mask,'Parent',app.UIAxes3);
        end

        % Button pushed function: ShowdefectButton_2
        function ShowdefectButton_2Pushed(app, event)
             num_defect=0;
            app.mask = bwareaopen(app.mask, 100);
            kernel=strel("square",4);
            app.mask=imdilate(app.mask,kernel);

            cc=bwconncomp(app.mask,4);
             imshow(app.mask,'Parent',app.UIAxes3);
             props = regionprops(cc, 'all');

          imshow(app.originalImage, 'Parent', app.UIAxes2_2);
          hold(app.UIAxes2_2, 'on');
         
            show_defect= app.nailCut_mask;
            for i = 1:length(props)
                 % Get the current size of the image
                 current_size = size(show_defect); 
            
                 % Resize the image using bicubic interpolation
                 resized_image = imresize(props(i).Image, current_size, 'bicubic');
                
                 matching = normxcorr2(show_defect,  resized_image);
                 [max_similarity, ~] = max(abs(matching(:)));
  
                box = props(i).BoundingBox;
                 if max_similarity>0.45
                      num_defect =num_defect+1;
                     rectangle(app.UIAxes2_2,'Position',box, 'EdgeColor', 'red', 'LineWidth', 2);
                      text(app.UIAxes2_2,props(i).Centroid(1)+10, props(i).Centroid(2)+15, 'Nail cut', ...
                          'Color', 'r', 'FontSize', 10, 'HorizontalAlignment', 'center')
            
            
                 end
            end

              hold(app.UIAxes2_2, 'off');
               
              app.NumdefectsEditField_2.Value=num_defect;
        end

        % Button pushed function: UploadTemplateButton
        function UploadTemplateButtonPushed(app, event)
                fileNameTemplate =readImage(app);

                selectedTemplate = imread(fileNameTemplate);
                app.Image_4.ImageSource=  selectedTemplate;
                app.getImageSourceTemplate = selectedTemplate;
                app.getImageSourceTemplate= im2gray( app.getImageSourceTemplate);
                app.getImageSourceTemplate = im2double( app.getImageSourceTemplate);
             
                thresh2=graythresh(app.getImageSourceTemplate);

                threshold_less1=app.getImageSourceTemplate<thresh2;
                threshold_greater1=app.getImageSourceTemplate>thresh2;
                app.nailCut_mask= zeros(size(app.getImageSourceTemplate));
                app.nailCut_mask(threshold_less1)=1;
                app.nailCut_mask(threshold_greater1)=0;
                imshow(app.nailCut_mask);
            
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
              app.TabGroup.SelectedTab = app.LatexGlovesTab;
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
                app.TabGroup.SelectedTab = app.LatexDirtyTab;
        end

        % Button pushed function: SelectImageButton_3
        function SelectImageButton_3Pushed(app, event)
              fileName =readImage(app);

              selectedImage = imread(fileName);

              app.Image_5.ImageSource= selectedImage;
              app.getImageSource=selectedImage;
              app.getImageSource= im2gray(app.getImageSource);
              app.getImageSource = im2double(app.getImageSource);
              app.showHist=   app.getImageSource;
              app.default_thresh=graythresh(app.getImageSource);
              app.mask=func(app,app.ThresholdSlider_3, app.CurrentThresholdValueEditField,app.default_thresh);
              imshow(app.mask,'Parent',app.UIAxes_2);
        end

        % Value changed function: ThresholdSlider_3
        function ThresholdSlider_3ValueChanged(app, event)
            value = app.ThresholdSlider_3.Value;
    
              app.mask=func(app,app.ThresholdSlider_3, app.CurrentThresholdValueEditField,value);
            
              imshow(app.mask,'Parent',app.UIAxes_2);
            
        end

        % Button pushed function: ShowDefectButton_2
        function ShowDefectButton_2Pushed(app, event)
            kernel=strel('square',13);
             app.mask=imclose( app.mask,kernel); 

            app.mask= bwareaopen(app.mask, 1000);
            cc=bwconncomp(app.mask,8);

             props = regionprops(cc, 'all');
            

             index_list = cc.PixelIdxList{1};


            show_dirty= zeros(size(app.getImageSource));
            show_dirty(index_list)=1;

            show_dirty=~show_dirty;

 
            overlay=imoverlay(app.getImageSource, show_dirty,"white");
            imshow(overlay,'Parent',app.UIAxes2_3);

            hold(app.UIAxes2_3, 'on');
              
            for i = 1:length(props)
    
                if(props(i).Area>5000 && props(i).Eccentricity>0.6 && props(i).Circularity<0.3)
                     box = props(i).BoundingBox;
                     rectangle(app.UIAxes2_3,'Position',box, 'EdgeColor', 'red', 'LineWidth', 2);
                     text(app.UIAxes2_3,props(i).Centroid(1)+10, props(i).Centroid(2)+15, 'Dirty ', ...
                         'Color', 'r', 'FontSize', 30, 'HorizontalAlignment', 'center')
                end
            end
            hold(app.UIAxes2_3, 'off');


        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.LatexGlovesTab;
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Image clicked function: home_latex_glove
        function home_latex_gloveImageClicked(app, event)
            app.TabGroup.SelectedTab = app.LatexGlovesTab;
        end

        % Image clicked function: home_cleaning_rubber_glove
        function home_cleaning_rubber_gloveImageClicked(app, event)
            app.TabGroup.SelectedTab = app.RubberGlovesTab;
        end

        % Image clicked function: home_nitrile_glove
        function home_nitrile_gloveImageClicked(app, event)
            app.TabGroup.SelectedTab = app.NitrileGlovesTab;
        end

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Image clicked function: rubber_hole_img
        function rubber_hole_imgImageClicked(app, event)
            app.TabGroup.SelectedTab = app.RubberHoleTab;
        end

        % Image clicked function: rubber_palm_tear_img
        function rubber_palm_tear_imgImageClicked(app, event)
            app.TabGroup.SelectedTab = app.RubberTearTab;
        end

        % Image clicked function: rubber_missing_finger_img
        function rubber_missing_finger_imgImageClicked(app, event)
            app.TabGroup.SelectedTab = app.RubberMissingTab;
        end

        % Button pushed function: BackButton_6
        function BackButton_6Pushed(app, event)
            app.TabGroup.SelectedTab = app.RubberGlovesTab;
        end

        % Button pushed function: SelectImageButton_4
        function SelectImageButton_4Pushed(app, event)
             fileName =readImage(app);

            selectedImage = imread(fileName);
            app.orig_rubber_hole_img.ImageSource= selectedImage;
            app.getImageSource=selectedImage;
            
            app.getImageSource= im2gray(app.getImageSource);
            app.getImageSource = im2double(app.getImageSource);
          
            
           %apply filter
             app.getImageSource=medfilt2(app.getImageSource); 
            % Display the image.
            app.orig_rubber_hole_img.ImageSource= selectedImage;

            app.default_thresh=graythresh(app.getImageSource) - 0.3;
            app.mask=func(app,app.ThresholdSlider_4, app.CurrentthresholdvalueEditField_3 ,app.default_thresh);
            imshow(app.mask,'Parent',app.holes_threshold_axes_2);
        end

        % Button pushed function: DefaultOptimalThresholdButton_4
        function DefaultOptimalThresholdButton_4Pushed(app, event)
            app.mask=func(app,app.ThresholdSlider_4, app.CurrentthresholdvalueEditField_3 ,app.default_thresh);
             imshow(app.mask,'Parent',app.holes_threshold_axes_2);
        end

        % Value changed function: ThresholdSlider_4
        function ThresholdSlider_4ValueChanged(app, event)
            value = app.ThresholdSlider_4.Value;
            app.mask=func(app,app.ThresholdSlider_4, app.CurrentthresholdvalueEditField_3,value);
            
            imshow(app.mask,'Parent',app.holes_threshold_axes_2);
        end

        % Button pushed function: ShowRubberHoleDefectButton
        function ShowRubberHoleDefectButtonPushed(app, event)
            element1=strel('disk',5);
            app.mask=imdilate(app.mask,element1);
            
            app.mask = bwareaopen(app.mask, 100);
            cc=bwconncomp(app.mask,4);
            props = regionprops(cc, 'all');
           
            
         imshow(app.getImageSource, 'Parent', app.rubber_hole_detected_axes);
          hold(app.rubber_hole_detected_axes, 'on');
         
          for i = 1:length(props)
    
            if(props(i).Circularity>0.5 || props(i).Eccentricity<0.6)
                 box = props(i).BoundingBox;
                 rectangle(app.rubber_hole_detected_axes, 'Position', box, 'EdgeColor', 'red', 'LineWidth', 2);
                text(app.rubber_hole_detected_axes,props(i).BoundingBox(1)+props(i).BoundingBox(3)+20, ...
                    props(i).BoundingBox(2)-60, 'Hole', 'Color', 'r', 'FontSize', 15, 'HorizontalAlignment', ...
                    'center')
            end
         end
          hold(app.rubber_hole_detected_axes, 'off');
        end

        % Button pushed function: BackButton_7
        function BackButton_7Pushed(app, event)
            app.TabGroup.SelectedTab = app.RubberGlovesTab;
        end

        % Button pushed function: SelectImageButton_5
        function SelectImageButton_5Pushed(app, event)
            fileName =readImage(app);

            selectedImage = imread(fileName);
            app.orig_missing_finger_img.ImageSource= selectedImage;
            app.getImageSource=selectedImage;
            
            app.getImageSource= im2gray(app.getImageSource);
            app.getImageSource = im2double(app.getImageSource);
          
            
           %apply filter
             app.getImageSource=medfilt2(app.getImageSource); 
            % Display the image.
            app.orig_missing_finger_img.ImageSource= selectedImage;

            app.default_thresh=graythresh(app.getImageSource) - 0.22;
            app.mask=func(app,app.ThresholdSlider_5, app.CurrentthresholdvalueEditField_4 ,app.default_thresh);
            imshow(app.mask,'Parent',app.missing_finger_thresholdAxes);
          
        end

        % Value changed function: ThresholdSlider_5
        function ThresholdSlider_5ValueChanged(app, event)
            value = app.ThresholdSlider_5.Value;
            app.mask=func(app,app.ThresholdSlider_5, app.CurrentthresholdvalueEditField_4,value);
            
            imshow(app.mask,'Parent',app.missing_finger_thresholdAxes);
        end

        % Button pushed function: DefaultOptimalThresholdButton_5
        function DefaultOptimalThresholdButton_5Pushed(app, event)
            app.mask=func(app,app.ThresholdSlider_5, app.CurrentthresholdvalueEditField_4 ,app.default_thresh);
             imshow(app.mask,'Parent',app.missing_finger_thresholdAxes);
        end

        % Button pushed function: SelectTemplateButton
        function SelectTemplateButtonPushed(app, event)
            fileNameTemplate =readImage(app);
            selectedTemplate = imread(fileNameTemplate);
            app.finger_template.ImageSource=  selectedTemplate;
            app.getImageSourceTemplate = selectedTemplate;
            app.getImageSourceTemplate= im2gray( app.getImageSourceTemplate);
            app.getImageSourceTemplate = im2double( app.getImageSourceTemplate);
         
            thresh2=graythresh(app.getImageSourceTemplate) + 0.05;

            threshold_less1=app.getImageSourceTemplate<thresh2;
            threshold_greater1=app.getImageSourceTemplate>thresh2;
            app.missingFinger_mask= zeros(size(app.getImageSourceTemplate));
            app.missingFinger_mask(threshold_less1)=1;
            app.missingFinger_mask(threshold_greater1)=0;
            imshow(app.missingFinger_mask);
        end

        % Button pushed function: ShowMissingFingerdefectButton
        function ShowMissingFingerdefectButtonPushed(app, event)
            kernel=strel("disk",11);

            app.mask=imopen(app.mask,kernel); 

            imshow(app.mask,'Parent',app.missing_finger_thresholdAxes);
 

            cc=bwconncomp(app.mask,4);
             imshow(app.mask,'Parent',app.missing_finger_thresholdAxes);
             props = regionprops(cc, 'all');

          imshow(app.getImageSource, 'Parent', app.missing_finger_detectedAxes);
          hold(app.missing_finger_detectedAxes, 'on');
         
            show_missing_part= app.missingFinger_mask;
            for i = 1:length(props)
                 % Get the current size of the image
                current_size = size(show_missing_part);
            
                 % Resize the image using bicubic interpolation
                 resized_image = imresize(props(i).Image, current_size, 'bicubic');
                
                 matching = normxcorr2(show_missing_part,  resized_image);
                 [max_similarity, ~] = max(abs(matching(:)));
  
                box = props(i).BoundingBox;
                 if max_similarity>0.4
                     rectangle(app.missing_finger_detectedAxes,'Position',box, 'EdgeColor', 'red', 'LineWidth', 2);
                      text(app.missing_finger_detectedAxes,props(i).BoundingBox(1)+props(i).BoundingBox(3)+20, ...
                          props(i).BoundingBox(2)-40, 'Missing Finger Part', 'Color', 'r', 'FontSize', ...
                          14, 'HorizontalAlignment', 'center')
            
            
                 end
            end
        end

        % Button pushed function: BackButton_8
        function BackButton_8Pushed(app, event)
            app.TabGroup.SelectedTab = app.RubberGlovesTab;
        end

        % Button pushed function: SelectImageButton_6
        function SelectImageButton_6Pushed(app, event)
             fileName =readImage(app);

            selectedImage = imread(fileName);
            app.orig_rubber_tear_img.ImageSource= selectedImage;
            app.getImageSource=selectedImage;
            
            app.getImageSource= im2gray(app.getImageSource);
            app.getImageSource = im2double(app.getImageSource);
          
            
           %apply filter
             app.getImageSource=medfilt2(app.getImageSource); 
            % Display the image.
            app.orig_rubber_tear_img.ImageSource= selectedImage;

            app.default_thresh=graythresh(app.getImageSource) - 0.38;
            app.mask=func(app,app.ThresholdSlider_6, app.CurrentthresholdvalueEditField_5,app.default_thresh);
            imshow(app.mask,'Parent',app.rubber_tear_thresholdAxes);
        end

        % Value changed function: ThresholdSlider_6
        function ThresholdSlider_6ValueChanged(app, event)
            value = app.ThresholdSlider_6.Value;
            app.mask=func(app,app.ThresholdSlider_6, app.CurrentthresholdvalueEditField_5,value);
            
            imshow(app.mask,'Parent',app.rubber_tear_thresholdAxes);
        end

        % Button pushed function: DefaultOptimalThresholdButton_6
        function DefaultOptimalThresholdButton_6Pushed(app, event)
            app.mask=func(app,app.ThresholdSlider_6, app.CurrentthresholdvalueEditField_5 ,app.default_thresh);
             imshow(app.mask,'Parent',app.rubber_tear_thresholdAxes);
        end

        % Button pushed function: ShowRubberTearDefectButton
        function ShowRubberTearDefectButtonPushed(app, event)
            app.mask = bwareaopen(app.mask, 100);
            cc=bwconncomp(app.mask,4);
            props = regionprops(cc, 'all');
           
            
           imshow(app.getImageSource, 'Parent', app.rubber_tear_detectedAxes);
           hold(app.rubber_tear_detectedAxes, 'on');
         
          for i = 1:length(props)
    
            if(props(i).Circularity>0.4 || props(i).Eccentricity<0.7)
                 box = props(i).BoundingBox;
                 rectangle(app.rubber_tear_detectedAxes, 'Position', box, 'EdgeColor', 'red', 'LineWidth', 2);
                 text(app.rubber_tear_detectedAxes,props(i).BoundingBox(1)+props(i).BoundingBox(3)+20, ...
                     props(i).BoundingBox(2)-60, 'Tear', 'Color', 'r', 'FontSize', 15, 'HorizontalAlignment', ...
                     'center')
            end
         end
          hold(app.rubber_tear_detectedAxes, 'off');
        end

        % Button pushed function: BackButton_9
        function BackButton_9Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;

        end

        % Button pushed function: BackButton_10
        function BackButton_10Pushed(app, event)
            app.TabGroup.SelectedTab = app.NitrileGlovesTab;

        end

        % Image clicked function: nitrile_hole_detect_image
        function nitrile_hole_detect_imageImageClicked(app, event)
            app.TabGroup.SelectedTab = app.NitrileHoleTab;
        end

        % Button pushed function: SelectImageButton_7
        function SelectImageButton_7Pushed(app, event)
            fileName =readImage(app);

            selectedImage = imread(fileName);
            app.orig_nitrile_hole_img_2.ImageSource= selectedImage;
            app.getImageSource=selectedImage;
            
            app.getImageSource= im2gray(app.getImageSource);
            app.getImageSource = im2double(app.getImageSource);
          
            
            %apply filter
            app.getImageSource=medfilt2(app.getImageSource); 

            % Display the image.    
            app.orig_nitrile_hole_img_2.ImageSource= selectedImage;

            app.default_thresh = graythresh(app.getImageSource) - 0.308;
            
            app.mask=func(app,app.ThresholdSlider_7, app.CurrentthresholdvalueEditField_6,app.default_thresh);
            
            
            imshow(app.mask, 'Parent', app.holes_threshold_axes_3);

            imshow(app.mask,'Parent',app.holes_threshold_axes_3);
        end

        % Value changed function: ThresholdSlider_7
        function ThresholdSlider_7ValueChanged(app, event)
            value = app.ThresholdSlider_7.Value;
            app.mask=func(app, app.ThresholdSlider_7, app.CurrentthresholdvalueEditField_6,value);
            
            imshow(app.mask,'Parent',app.holes_threshold_axes_3);

        end

        % Button pushed function: ShowNitrileHoleDefectButton_2
        function ShowNitrileHoleDefectButton_2Pushed(app, event)

            element1 = strel('disk', 5);
            app.mask = imdilate(app.mask, element1);
            app.mask = bwareaopen(app.mask, 100);
            cc = bwconncomp(app.mask, 4);
            props = regionprops(cc, 'all');
        
            imshow(app.getImageSource, 'Parent', app.nitrile_hole_detected_axes_2);
            hold(app.nitrile_hole_detected_axes_2, 'on');
            
            for i = 1:length(props)
                circularity = props(i).Circularity;
                eccentricity = props(i).Eccentricity;
                aspectRatio = props(i).BoundingBox(3) / props(i).BoundingBox(4);
                
                % Check if the object meets the circularity and aspect ratio criteria
                if circularity > 0.8  && aspectRatio <= 1
                    box = props(i).BoundingBox;
                    rectangle(app.nitrile_hole_detected_axes_2, 'Position', box, 'EdgeColor', 'red', ...
                        'LineWidth', 2);
                    text(app.nitrile_hole_detected_axes_2, box(1) + box(3) + 20, box(2) - 60, 'Hole', ...
                        'Color', 'r', 'FontSize', 15, 'HorizontalAlignment', 'center');
            
            
                end
            end

            
            hold(app.nitrile_hole_detected_axes_2, 'off');

        end

        % Button pushed function: DefaultOptimalThresholdButton_7
        function DefaultOptimalThresholdButton_7Pushed(app, event)
            app.mask=func(app,app.ThresholdSlider_7, app.CurrentthresholdvalueEditField_6 ,app.default_thresh);
             imshow(app.mask,'Parent',app.holes_threshold_axes_3);
        end

        % Image clicked function: orig_nitrile_rip
        function orig_nitrile_ripImageClicked(app, event)
            
        end

        % Button pushed function: SelectImageButton_8
        function SelectImageButton_8Pushed(app, event)
            fileName = readImage(app);
            
            selectedImage = imread(fileName);
            app.orig_nitrile_rip.ImageSource = selectedImage;
            app.getImageSource = selectedImage;
            
            app.getImageSource = im2gray(app.getImageSource);
            app.getImageSource = im2double(app.getImageSource);
            
            % Apply filter
            app.getImageSource = medfilt2(app.getImageSource);
            
            % Display the image.
            app.orig_nitrile_rip.ImageSource = selectedImage;
            
            app.default_thresh = graythresh(app.getImageSource) - 0.2;
            
            app.mask = func2(app, app.ThresholdSlider_8, app.CurrentthresholdvalueEditField_7, ...
                app.default_thresh, graythresh(app.getImageSource));
            
            imshow(app.mask, 'Parent', app.tear_threshold_axes);
            imshow(app.mask, 'Parent', app.tear_threshold_axes);


        end

        % Value changed function: ThresholdSlider_8
        function ThresholdSlider_8ValueChanged(app, event)
            value = app.ThresholdSlider_8.Value;
            app.mask = func2(app, app.ThresholdSlider_8, app.CurrentthresholdvalueEditField_7, value, graythresh(app.getImageSource));
        
            imshow(app.mask, 'Parent', app.tear_threshold_axes);

        end

        % Button pushed function: DefaultOptimalThresholdButton_8
        function DefaultOptimalThresholdButton_8Pushed(app, event)
            app.mask=func2(app,app.ThresholdSlider_8, app.CurrentthresholdvalueEditField_7 ,app.default_thresh);
             imshow(app.mask,'Parent',app.tear_threshold_axes);
        end

        % Button pushed function: ShowRipDefectButton_3
        function ShowRipDefectButton_3Pushed(app, event)
            cc = bwconncomp(app.mask, 4);
            props = regionprops(cc, 'all');
            display(props);
            imshow(app.getImageSource, 'Parent', app.nitrile_rip_detected_axes);
            hold(app.nitrile_rip_detected_axes, 'on');


            for i = 1:length(props)
                circularity = props(i).Circularity;
                eccentricity = props(i).Eccentricity;
                aspectRatio = props(i).BoundingBox(3) / props(i).BoundingBox(4);
                area = props(i).Area;
                if area < 10000
                    box = props(i).BoundingBox;
                    rectangle(app.nitrile_rip_detected_axes, 'Position', box, 'EdgeColor', ...
                        'red', 'LineWidth', 2);
                    text(app.nitrile_rip_detected_axes, box(1) + box(3) + 20, box(2) - 60, ...
                        'Rip', 'Color', 'r', 'FontSize', 15, 'HorizontalAlignment', 'center');
                end
            end
            hold(app.nitrile_rip_detected_axes, 'off');
        end

        % Image clicked function: nitrile_tear_wrist_detect_image
        function nitrile_tear_wrist_detect_imageImageClicked(app, event)
            app.TabGroup.SelectedTab = app.NitrileRipTab;
        end

        % Button pushed function: BackButton_11
        function BackButton_11Pushed(app, event)
            app.TabGroup.SelectedTab = app.NitrileGlovesTab;
        end

        % Button pushed function: SelectImageButton_9
        function SelectImageButton_9Pushed(app, event)
            fileName = readImage(app);

            selectedImage = imread(fileName);


            app.orig_nitrile_stain_img.ImageSource = selectedImage;
            app.getImageSource = selectedImage;
            
            app.channel1Min = 0.371;
            app.channel1Max = 0.654;

            app.HUElowerSlider.Value = app.channel1Min;
            app.HUEupperSlider.Value = app.channel1Max;

            [BW, ] = colorSegment(app, selectedImage,app.channel1Min, app.channel1Max);
            
            app.mask = BW;
            
            imshow(app.mask, 'Parent', app.stain_threshold_axes);

        end

        % Value changed function: HUElowerSlider
        function HUElowerSliderValueChanged(app, event)
            value = app.HUElowerSlider.Value;
            app.channel1Min = value;
            [BW, ] = colorSegment(app, app.getImageSource, app.channel1Min, app.channel1Max);
            app.mask = BW;
            imshow(app.mask, 'Parent', app.stain_threshold_axes);
        end

        % Value changed function: HUEupperSlider
        function HUEupperSliderValueChanged(app, event)
            value = app.HUEupperSlider.Value;
            app.channel1Max = value;
            [BW, ] = colorSegment(app, app.getImageSource, app.channel1Min, app.channel1Max);
            app.mask = BW;
            imshow(app.mask, 'Parent', app.stain_threshold_axes);

        end

        % Button pushed function: DefaultOptimalThresholdButton_9
        function DefaultOptimalThresholdButton_9Pushed(app, event)

            app.channel1Min = 0.371;
            app.channel1Max = 0.654;
            [BW, ] = colorSegment(app, app.getImageSource, app.channel1Min, app.channel1Max);
            app.mask = BW;
            imshow(app.mask, 'Parent', app.stain_threshold_axes);

        end

        % Button pushed function: BackButton_12
        function BackButton_12Pushed(app, event)
            app.TabGroup.SelectedTab = app.NitrileGlovesTab;
            
        end

        % Button pushed function: ShowNitrileStainDefectButton_4
        function ShowNitrileStainDefectButton_4Pushed(app, event)
            element1 = strel('disk', 5);
            app.mask = imdilate(app.mask, element1);
            app.mask = bwareaopen(app.mask, 100);
            cc = bwconncomp(app.mask, 4);
            props = regionprops(cc, 'all');
            display(props);
            imshow(app.getImageSource, 'Parent', app.nitrile_stain_detected_axes);
            hold(app.nitrile_stain_detected_axes, 'on');

            for i = 1:length(props)
                area = props(i).Area;
                aspectRatio = props(i).BoundingBox(3) / props(i).BoundingBox(4);

                if area < 5000 && aspectRatio < 2
                    box = props(i).BoundingBox;
                    rectangle(app.nitrile_stain_detected_axes, 'Position', box, 'EdgeColor', 'red', ...
                        'LineWidth', 2);
                    text(app.nitrile_stain_detected_axes, box(1) + box(3) + 20, box(2) - 60, ...
                        'Stain', 'Color', 'r', 'FontSize', 15, 'HorizontalAlignment', 'center');
                end
            end
            
            
            hold(app.nitrile_stain_detected_axes, 'off');

        end

        % Button pushed function: DefaultOptimalThresholdButton_3
        function DefaultOptimalThresholdButton_3Pushed(app, event)
             app.mask=func(app,app.ThresholdSlider_3, app.CurrentThresholdValueEditField ,app.default_thresh);
             imshow(app.mask,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ShowhistogramButton
        function ShowhistogramButtonPushed(app, event)
           
            hist=imhist(app.showHist);
            xvalue = linspace(0, 1, numel(hist));
            bar(app.UIAxes4, xvalue,hist );
           
           
        end

        % Size changed function: NitrileStainTab
        function NitrileStainTabSizeChanged(app, event)
             
        end

        % Image clicked function: nitrile_stain_detect_image
        function nitrile_stain_detect_imageImageClicked(app, event)
            app.TabGroup.SelectedTab = app.NitrileStainTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WhatsWrongwithyourGlovesLabel = uilabel(app.HomeTab);
            app.WhatsWrongwithyourGlovesLabel.Text = 'What''s Wrong with your Gloves?';
            app.SelectwhichtypeofglovethatyouwanttodetectLabel = uilabel(app.HomeTab);
            app.SelectwhichtypeofglovethatyouwanttodetectLabel.Text = 'Select which type of glove that you want to detect';
            app.home_latex_glove = uiimage(app.HomeTab);
            app.home_latex_glove.ImageClickedFcn = createCallbackFcn(app, @home_latex_gloveImageClicked, true);
            app.home_latex_glove.ImageSource = fullfile(pathToMLAPP, 'latex_gloves', 'latex_glove.jpg');
            app.home_cleaning_rubber_glove = uiimage(app.HomeTab);
            app.home_cleaning_rubber_glove.ImageClickedFcn = createCallbackFcn(app, @home_cleaning_rubber_gloveImageClicked, true);
            app.home_cleaning_rubber_glove.ImageSource = fullfile(pathToMLAPP, 'rubber_gloves', 'rubber_cleaning_gloves.jpeg');
            app.home_nitrile_glove = uiimage(app.HomeTab);
            app.home_nitrile_glove.ImageClickedFcn = createCallbackFcn(app, @home_nitrile_gloveImageClicked, true);
            app.home_nitrile_glove.ImageSource = fullfile(pathToMLAPP, 'nitrile_gloves', 'nitrile_glove.jpg');
            app.LatexGlovesLabel = uilabel(app.HomeTab);
            app.LatexGlovesLabel.Text = 'Latex Gloves';
            app.CleaningRubberGlovesLabel = uilabel(app.HomeTab);
            app.CleaningRubberGlovesLabel.Text = 'Cleaning Rubber Gloves';
            app.NitrileGlovesLabel = uilabel(app.HomeTab);
            app.NitrileGlovesLabel.Text = 'Nitrile Gloves';
            app.LatexGlovesTab = uitab(app.TabGroup);
            app.LatexGlovesTab.Title = 'Latex Gloves';
            app.Image = uiimage(app.LatexGlovesTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'latex_gloves', 'glove_holes.jpg');
            app.DetectholeLabel = uilabel(app.LatexGlovesTab);
            app.DetectholeLabel.Text = 'Detect hole';
            app.Image2 = uiimage(app.LatexGlovesTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'latex_gloves', 'latex_nail_cut.jpg');
            app.DetectcutbynailsLabel = uilabel(app.LatexGlovesTab);
            app.DetectcutbynailsLabel.Text = 'Detect cut by nails';
            app.Image3 = uiimage(app.LatexGlovesTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'latex_gloves', 'latex_dirty.jpg');
            app.DetectdirtyLabel = uilabel(app.LatexGlovesTab);
            app.DetectdirtyLabel.Text = 'Detect dirty';
            app.LatexGloveDefectDetectionLabel = uilabel(app.LatexGlovesTab);
            app.LatexGloveDefectDetectionLabel.Text = 'Latex Glove Defect Detection';
            app.SelectwhichdefecttodetectbasedontheimagebelowLabel = uilabel(app.LatexGlovesTab);
            app.SelectwhichdefecttodetectbasedontheimagebelowLabel.Text = 'Select which defect to detect based on the image below:';
            app.BackButton_4 = uibutton(app.LatexGlovesTab, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back ';
            app.LatexHoleTab = uitab(app.TabGroup);
            app.LatexHoleTab.Title = 'Latex Hole ';
            app.holes_threshold_axes = uiaxes(app.LatexHoleTab);
            title(app.holes_threshold_axes, 'Threshold Image')
            app.UIAxes2 = uiaxes(app.LatexHoleTab);
            title(app.UIAxes2, 'Hole Detected')
            app.UIAxes4 = uiaxes(app.LatexHoleTab);
            title(app.UIAxes4, 'Histogram')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XTick = [0 0.25 0.5 0.75 1];
            app.Image_2 = uiimage(app.LatexHoleTab);
            app.SelectImageButton = uibutton(app.LatexHoleTab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.BackButton = uibutton(app.LatexHoleTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back ';
            app.ShowDefectButton = uibutton(app.LatexHoleTab, 'push');
            app.ShowDefectButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDefectButtonPushed, true);
            app.ShowDefectButton.Text = 'Show Defect';
            app.DefaultOptimalThresholdButton = uibutton(app.LatexHoleTab, 'push');
            app.DefaultOptimalThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButtonPushed, true);
            app.DefaultOptimalThresholdButton.Text = 'Default Optimal Threshold';
            app.ThresholdSliderLabel = uilabel(app.LatexHoleTab);
            app.ThresholdSliderLabel.Text = 'Threshold Slider';
            app.ThresholdSlider = uislider(app.LatexHoleTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.CurrentthresholdvalueEditFieldLabel = uilabel(app.LatexHoleTab);
            app.CurrentthresholdvalueEditFieldLabel.Text = 'Current threshold value';
            app.CurrentthresholdvalueEditField = uieditfield(app.LatexHoleTab, 'text');
            app.OriginalImageLabel = uilabel(app.LatexHoleTab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.LatexGlovesHoleDefectDetectionLabel = uilabel(app.LatexHoleTab);
            app.LatexGlovesHoleDefectDetectionLabel.Text = 'Latex Gloves Hole Defect Detection';
            app.NumdefectsEditFieldLabel = uilabel(app.LatexHoleTab);
            app.NumdefectsEditFieldLabel.Text = 'Num defects';
            app.NumdefectsEditField = uieditfield(app.LatexHoleTab, 'numeric');
            app.ShowhistogramButton = uibutton(app.LatexHoleTab, 'push');
            app.ShowhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowhistogramButtonPushed, true);
            app.ShowhistogramButton.Text = 'Show histogram';
            app.LatexNailCutTab = uitab(app.TabGroup);
            app.LatexNailCutTab.Title = 'Latex Nail Cut';
            app.UIAxes3 = uiaxes(app.LatexNailCutTab);
            title(app.UIAxes3, 'Threshold Image')
            app.UIAxes2_2 = uiaxes(app.LatexNailCutTab);
            title(app.UIAxes2_2, 'Nail Cut Detected')
            app.Image_3 = uiimage(app.LatexNailCutTab);
            app.SelectImageButton_2 = uibutton(app.LatexNailCutTab, 'push');
            app.SelectImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_2Pushed, true);
            app.SelectImageButton_2.Text = 'Select Image';
            app.BackButton_2 = uibutton(app.LatexNailCutTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back ';
            app.Image_4 = uiimage(app.LatexNailCutTab);
            app.UploadTemplateButton = uibutton(app.LatexNailCutTab, 'push');
            app.UploadTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTemplateButtonPushed, true);
            app.UploadTemplateButton.Text = 'Upload Template';
            app.DefaultOptimalThresholdButton_2 = uibutton(app.LatexNailCutTab, 'push');
            app.DefaultOptimalThresholdButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_2Pushed, true);
            app.DefaultOptimalThresholdButton_2.Text = 'Default Optimal Threshold';
            app.ThresholdSlider_2Label = uilabel(app.LatexNailCutTab);
            app.ThresholdSlider_2Label.Text = 'Threshold Slider';
            app.ThresholdSlider_2 = uislider(app.LatexNailCutTab);
            app.ThresholdSlider_2.Limits = [0 1];
            app.ThresholdSlider_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_2ValueChanged, true);
            app.CurrentthresholdvalueEditField_2Label = uilabel(app.LatexNailCutTab);
            app.CurrentthresholdvalueEditField_2Label.Text = 'Current threshold value';
            app.CurrentthresholdvalueEditField_2 = uieditfield(app.LatexNailCutTab, 'text');
            app.ShowdefectButton_2 = uibutton(app.LatexNailCutTab, 'push');
            app.ShowdefectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowdefectButton_2Pushed, true);
            app.ShowdefectButton_2.Text = 'Show defect';
            app.OriginalImageLabel_2 = uilabel(app.LatexNailCutTab);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.SelectedTemplateLabel = uilabel(app.LatexNailCutTab);
            app.SelectedTemplateLabel.Text = 'Selected Template';
            app.LatexGlovesNailCutDefectDetectionLabel = uilabel(app.LatexNailCutTab);
            app.LatexGlovesNailCutDefectDetectionLabel.Text = 'Latex Gloves Nail Cut Defect Detection';
            app.NumdefectsEditField_2Label = uilabel(app.LatexNailCutTab);
            app.NumdefectsEditField_2Label.Text = 'Num defects';
            app.NumdefectsEditField_2 = uieditfield(app.LatexNailCutTab, 'numeric');
            app.LatexDirtyTab = uitab(app.TabGroup);
            app.LatexDirtyTab.Title = 'Latex Dirty';
            app.UIAxes_2 = uiaxes(app.LatexDirtyTab);
            title(app.UIAxes_2, 'Threshold Image')
            app.UIAxes2_3 = uiaxes(app.LatexDirtyTab);
            title(app.UIAxes2_3, 'Dirty Detected')
            app.Image_5 = uiimage(app.LatexDirtyTab);
            app.SelectImageButton_3 = uibutton(app.LatexDirtyTab, 'push');
            app.SelectImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_3Pushed, true);
            app.SelectImageButton_3.Text = 'Select Image';
            app.BackButton_3 = uibutton(app.LatexDirtyTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back ';
            app.ShowDefectButton_2 = uibutton(app.LatexDirtyTab, 'push');
            app.ShowDefectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowDefectButton_2Pushed, true);
            app.ShowDefectButton_2.Text = 'Show Defect';
            app.DefaultOptimalThresholdButton_3 = uibutton(app.LatexDirtyTab, 'push');
            app.DefaultOptimalThresholdButton_3.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_3Pushed, true);
            app.DefaultOptimalThresholdButton_3.Text = 'Default Optimal Threshold';
            app.ThresholdSlider_3Label = uilabel(app.LatexDirtyTab);
            app.ThresholdSlider_3Label.Text = 'Threshold Slider';
            app.ThresholdSlider_3 = uislider(app.LatexDirtyTab);
            app.ThresholdSlider_3.Limits = [0 1];
            app.ThresholdSlider_3.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_3ValueChanged, true);
            app.CurrentThresholdValueEditFieldLabel = uilabel(app.LatexDirtyTab);
            app.CurrentThresholdValueEditFieldLabel.Text = 'Current Threshold Value';
            app.CurrentThresholdValueEditField = uieditfield(app.LatexDirtyTab, 'text');
            app.OriginalImageLabel_3 = uilabel(app.LatexDirtyTab);
            app.OriginalImageLabel_3.Text = 'Original Image';
            app.LatexGlovesDirtyDefectDetectionLabel = uilabel(app.LatexDirtyTab);
            app.LatexGlovesDirtyDefectDetectionLabel.Text = 'Latex Gloves Dirty Defect Detection';
            app.RubberGlovesTab = uitab(app.TabGroup);
            app.RubberGlovesTab.Title = 'Rubber Gloves';
            app.BackButton_5 = uibutton(app.RubberGlovesTab, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back ';
            app.CleaningRubberGloveDefectDetectionLabel = uilabel(app.RubberGlovesTab);
            app.CleaningRubberGloveDefectDetectionLabel.Text = 'Cleaning Rubber Glove Defect Detection';
            app.SelectwhichdefecttodetectbasedontheimagebelowLabel_2 = uilabel(app.RubberGlovesTab);
            app.SelectwhichdefecttodetectbasedontheimagebelowLabel_2.Text = 'Select which defect to detect based on the image below:';
            app.rubber_hole_img = uiimage(app.RubberGlovesTab);
            app.rubber_hole_img.ImageClickedFcn = createCallbackFcn(app, @rubber_hole_imgImageClicked, true);
            app.rubber_hole_img.ImageSource = fullfile(pathToMLAPP, 'rubber_gloves', 'rubber_hole.jpg');
            app.rubber_palm_tear_img = uiimage(app.RubberGlovesTab);
            app.rubber_palm_tear_img.ImageClickedFcn = createCallbackFcn(app, @rubber_palm_tear_imgImageClicked, true);
            app.rubber_palm_tear_img.ImageSource = fullfile(pathToMLAPP, 'rubber_gloves', 'rubber_palm_tear.jpg');
            app.rubber_missing_finger_img = uiimage(app.RubberGlovesTab);
            app.rubber_missing_finger_img.ImageClickedFcn = createCallbackFcn(app, @rubber_missing_finger_imgImageClicked, true);
            app.rubber_missing_finger_img.ImageSource = fullfile(pathToMLAPP, 'rubber_gloves', 'missing_finger.jpg');
            app.DetectholeLabel_2 = uilabel(app.RubberGlovesTab);
            app.DetectholeLabel_2.Text = 'Detect hole';
            app.DetecttearatpalmareaLabel = uilabel(app.RubberGlovesTab);
            app.DetecttearatpalmareaLabel.Text = 'Detect tear at palm area';
            app.DetectmissingfingerpartLabel = uilabel(app.RubberGlovesTab);
            app.DetectmissingfingerpartLabel.Text = 'Detect missing finger part';
            app.RubberHoleTab = uitab(app.TabGroup);
            app.RubberHoleTab.Title = 'Rubber Hole';
            app.holes_threshold_axes_2 = uiaxes(app.RubberHoleTab);
            title(app.holes_threshold_axes_2, 'Threshold Image')
            app.rubber_hole_detected_axes = uiaxes(app.RubberHoleTab);
            title(app.rubber_hole_detected_axes, 'Hole Detected')
            app.BackButton_6 = uibutton(app.RubberHoleTab, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButton_6Pushed, true);
            app.BackButton_6.Text = 'Back ';
            app.RubberGlovesHoleDefectDetectionLabel = uilabel(app.RubberHoleTab);
            app.RubberGlovesHoleDefectDetectionLabel.Text = 'Rubber Gloves Hole Defect Detection';
            app.orig_rubber_hole_img = uiimage(app.RubberHoleTab);
            app.SelectImageButton_4 = uibutton(app.RubberHoleTab, 'push');
            app.SelectImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_4Pushed, true);
            app.SelectImageButton_4.Text = 'Select Image';
            app.ShowRubberHoleDefectButton = uibutton(app.RubberHoleTab, 'push');
            app.ShowRubberHoleDefectButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRubberHoleDefectButtonPushed, true);
            app.ShowRubberHoleDefectButton.Text = 'Show Defect';
            app.DefaultOptimalThresholdButton_4 = uibutton(app.RubberHoleTab, 'push');
            app.DefaultOptimalThresholdButton_4.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_4Pushed, true);
            app.DefaultOptimalThresholdButton_4.Text = 'Default Optimal Threshold';
            app.OriginalImageLabel_4 = uilabel(app.RubberHoleTab);
            app.OriginalImageLabel_4.Text = 'Original Image';
            app.ThresholdSlider_4Label = uilabel(app.RubberHoleTab);
            app.ThresholdSlider_4Label.Text = 'Threshold Slider';
            app.ThresholdSlider_4 = uislider(app.RubberHoleTab);
            app.ThresholdSlider_4.Limits = [0 1];
            app.ThresholdSlider_4.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_4ValueChanged, true);
            app.CurrentthresholdvalueEditField_3Label = uilabel(app.RubberHoleTab);
            app.CurrentthresholdvalueEditField_3Label.Text = 'Current threshold value';
            app.CurrentthresholdvalueEditField_3 = uieditfield(app.RubberHoleTab, 'text');
            app.RubberTearTab = uitab(app.TabGroup);
            app.RubberTearTab.Title = 'Rubber Tear';
            app.rubber_tear_thresholdAxes = uiaxes(app.RubberTearTab);
            title(app.rubber_tear_thresholdAxes, 'Threshold Image')
            app.rubber_tear_detectedAxes = uiaxes(app.RubberTearTab);
            title(app.rubber_tear_detectedAxes, 'Palm Tear Detected')
            app.BackButton_8 = uibutton(app.RubberTearTab, 'push');
            app.BackButton_8.ButtonPushedFcn = createCallbackFcn(app, @BackButton_8Pushed, true);
            app.BackButton_8.Text = 'Back ';
            app.RubberGlovesPalmTearDefectDetectionLabel = uilabel(app.RubberTearTab);
            app.RubberGlovesPalmTearDefectDetectionLabel.Text = 'Rubber Gloves Palm Tear Defect Detection';
            app.orig_rubber_tear_img = uiimage(app.RubberTearTab);
            app.SelectImageButton_6 = uibutton(app.RubberTearTab, 'push');
            app.SelectImageButton_6.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_6Pushed, true);
            app.SelectImageButton_6.Text = 'Select Image';
            app.ShowRubberTearDefectButton = uibutton(app.RubberTearTab, 'push');
            app.ShowRubberTearDefectButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRubberTearDefectButtonPushed, true);
            app.ShowRubberTearDefectButton.Text = 'Show Defect';
            app.DefaultOptimalThresholdButton_6 = uibutton(app.RubberTearTab, 'push');
            app.DefaultOptimalThresholdButton_6.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_6Pushed, true);
            app.DefaultOptimalThresholdButton_6.Text = 'Default Optimal Threshold';
            app.OriginalImageLabel_6 = uilabel(app.RubberTearTab);
            app.OriginalImageLabel_6.Text = 'Original Image';
            app.ThresholdSlider_6Label = uilabel(app.RubberTearTab);
            app.ThresholdSlider_6Label.Text = 'Threshold Slider';
            app.ThresholdSlider_6 = uislider(app.RubberTearTab);
            app.ThresholdSlider_6.Limits = [0 1];
            app.ThresholdSlider_6.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_6ValueChanged, true);
            app.CurrentthresholdvalueEditField_5Label = uilabel(app.RubberTearTab);
            app.CurrentthresholdvalueEditField_5Label.Text = 'Current threshold value';
            app.CurrentthresholdvalueEditField_5 = uieditfield(app.RubberTearTab, 'text');
            app.RubberMissingTab = uitab(app.TabGroup);
            app.RubberMissingTab.Title = 'Rubber Missing';
            app.missing_finger_detectedAxes = uiaxes(app.RubberMissingTab);
            title(app.missing_finger_detectedAxes, 'Missing Finger Part Detected')
            app.missing_finger_thresholdAxes = uiaxes(app.RubberMissingTab);
            title(app.missing_finger_thresholdAxes, 'Threshold Image')
            app.BackButton_7 = uibutton(app.RubberMissingTab, 'push');
            app.BackButton_7.ButtonPushedFcn = createCallbackFcn(app, @BackButton_7Pushed, true);
            app.BackButton_7.Text = 'Back ';
            app.RubberGlovesMissingFingerPartDefectDetectionLabel = uilabel(app.RubberMissingTab);
            app.RubberGlovesMissingFingerPartDefectDetectionLabel.Text = 'Rubber Gloves Missing Finger Part Defect Detection';
            app.OriginalImageLabel_5 = uilabel(app.RubberMissingTab);
            app.OriginalImageLabel_5.Text = 'Original Image';
            app.orig_missing_finger_img = uiimage(app.RubberMissingTab);
            app.ThresholdSlider_5Label = uilabel(app.RubberMissingTab);
            app.ThresholdSlider_5Label.Text = 'Threshold Slider';
            app.ThresholdSlider_5 = uislider(app.RubberMissingTab);
            app.ThresholdSlider_5.Limits = [0 1];
            app.ThresholdSlider_5.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_5ValueChanged, true);
            app.CurrentthresholdvalueEditField_4Label = uilabel(app.RubberMissingTab);
            app.CurrentthresholdvalueEditField_4Label.Text = 'Current threshold value';
            app.CurrentthresholdvalueEditField_4 = uieditfield(app.RubberMissingTab, 'text');
            app.SelectImageButton_5 = uibutton(app.RubberMissingTab, 'push');
            app.SelectImageButton_5.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_5Pushed, true);
            app.SelectImageButton_5.Text = 'Select Image';
            app.DefaultOptimalThresholdButton_5 = uibutton(app.RubberMissingTab, 'push');
            app.DefaultOptimalThresholdButton_5.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_5Pushed, true);
            app.DefaultOptimalThresholdButton_5.Text = 'Default Optimal Threshold';
            app.ShowMissingFingerdefectButton = uibutton(app.RubberMissingTab, 'push');
            app.ShowMissingFingerdefectButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMissingFingerdefectButtonPushed, true);
            app.ShowMissingFingerdefectButton.Text = 'Show defect';
            app.finger_template = uiimage(app.RubberMissingTab);
            app.TemplateImageLabel = uilabel(app.RubberMissingTab);
            app.TemplateImageLabel.Text = 'Template Image';
            app.SelectTemplateButton = uibutton(app.RubberMissingTab, 'push');
            app.SelectTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTemplateButtonPushed, true);
            app.SelectTemplateButton.Text = 'Select Template';
            app.NitrileGlovesTab = uitab(app.TabGroup);
            app.NitrileGlovesTab.Title = 'Nitrile Gloves';
            app.BackButton_9 = uibutton(app.NitrileGlovesTab, 'push');
            app.BackButton_9.ButtonPushedFcn = createCallbackFcn(app, @BackButton_9Pushed, true);
            app.BackButton_9.Text = 'Back ';
            app.NitrileGloveDefectDetectionLabel = uilabel(app.NitrileGlovesTab);
            app.NitrileGloveDefectDetectionLabel.Text = 'Nitrile Glove Defect Detection';
            app.SelectwhichdefecttodetectbasedontheimagebelowLabel_3 = uilabel(app.NitrileGlovesTab);
            app.SelectwhichdefecttodetectbasedontheimagebelowLabel_3.Text = 'Select which defect to detect based on the image below:';
            app.nitrile_hole_detect_image = uiimage(app.NitrileGlovesTab);
            app.nitrile_hole_detect_image.ImageClickedFcn = createCallbackFcn(app, @nitrile_hole_detect_imageImageClicked, true);
            app.nitrile_hole_detect_image.ImageSource = fullfile(pathToMLAPP, 'nitrile_gloves', 'hole.jpg');
            app.nitrile_tear_wrist_detect_image = uiimage(app.NitrileGlovesTab);
            app.nitrile_tear_wrist_detect_image.ImageClickedFcn = createCallbackFcn(app, @nitrile_tear_wrist_detect_imageImageClicked, true);
            app.nitrile_tear_wrist_detect_image.ImageSource = fullfile(pathToMLAPP, 'nitrile_gloves', 'rip2.jpg');
            app.nitrile_stain_detect_image = uiimage(app.NitrileGlovesTab);
            app.nitrile_stain_detect_image.ImageClickedFcn = createCallbackFcn(app, @nitrile_stain_detect_imageImageClicked, true);
            app.nitrile_stain_detect_image.ImageSource = fullfile(pathToMLAPP, 'nitrile_gloves', 'stain (2).jpg');
            app.DetectholeLabel_3 = uilabel(app.NitrileGlovesTab);
            app.DetectholeLabel_3.Text = 'Detect hole';
            app.DetectRipLabel = uilabel(app.NitrileGlovesTab);
            app.DetectRipLabel.Text = 'Detect Rip';
            app.DetectstainLabel = uilabel(app.NitrileGlovesTab);
            app.DetectstainLabel.Text = 'Detect stain';
            app.NitrileHoleTab = uitab(app.TabGroup);
            app.NitrileHoleTab.Title = 'Nitrile Hole';
            app.holes_threshold_axes_3 = uiaxes(app.NitrileHoleTab);
            title(app.holes_threshold_axes_3, 'Threshold Image')
            app.nitrile_hole_detected_axes_2 = uiaxes(app.NitrileHoleTab);
            title(app.nitrile_hole_detected_axes_2, 'Hole Detected')
            app.BackButton_10 = uibutton(app.NitrileHoleTab, 'push');
            app.BackButton_10.ButtonPushedFcn = createCallbackFcn(app, @BackButton_10Pushed, true);
            app.BackButton_10.Text = 'Back ';
            app.NitrileGlovesHoleDetectionLabel = uilabel(app.NitrileHoleTab);
            app.NitrileGlovesHoleDetectionLabel.Text = 'Nitrile Gloves Hole Detection';
            app.orig_nitrile_hole_img_2 = uiimage(app.NitrileHoleTab);
            app.SelectImageButton_7 = uibutton(app.NitrileHoleTab, 'push');
            app.SelectImageButton_7.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_7Pushed, true);
            app.SelectImageButton_7.Text = 'Select Image';
            app.ShowNitrileHoleDefectButton_2 = uibutton(app.NitrileHoleTab, 'push');
            app.ShowNitrileHoleDefectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowNitrileHoleDefectButton_2Pushed, true);
            app.ShowNitrileHoleDefectButton_2.Text = 'Show Defect';
            app.DefaultOptimalThresholdButton_7 = uibutton(app.NitrileHoleTab, 'push');
            app.DefaultOptimalThresholdButton_7.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_7Pushed, true);
            app.DefaultOptimalThresholdButton_7.Text = 'Default Optimal Threshold';
            app.OriginalImageLabel_7 = uilabel(app.NitrileHoleTab);
            app.OriginalImageLabel_7.Text = 'Original Image';
            app.ThresholdSlider_7Label = uilabel(app.NitrileHoleTab);
            app.ThresholdSlider_7Label.Text = 'Threshold Slider';
            app.ThresholdSlider_7 = uislider(app.NitrileHoleTab);
            app.ThresholdSlider_7.Limits = [0 1];
            app.ThresholdSlider_7.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_7ValueChanged, true);
            app.CurrentthresholdvalueEditField_6Label = uilabel(app.NitrileHoleTab);
            app.CurrentthresholdvalueEditField_6Label.Text = 'Current threshold value';
            app.CurrentthresholdvalueEditField_6 = uieditfield(app.NitrileHoleTab, 'text');
            app.NitrileRipTab = uitab(app.TabGroup);
            app.NitrileRipTab.Title = 'Nitrile Rip';
            app.tear_threshold_axes = uiaxes(app.NitrileRipTab);
            title(app.tear_threshold_axes, 'Threshold Image')
            app.nitrile_rip_detected_axes = uiaxes(app.NitrileRipTab);
            title(app.nitrile_rip_detected_axes, 'Tear Detected')
            app.BackButton_11 = uibutton(app.NitrileRipTab, 'push');
            app.BackButton_11.ButtonPushedFcn = createCallbackFcn(app, @BackButton_11Pushed, true);
            app.BackButton_11.Text = 'Back ';
            app.NitrileGlovesRipDetectionLabel = uilabel(app.NitrileRipTab);
            app.NitrileGlovesRipDetectionLabel.Text = 'Nitrile Gloves Rip Detection';
            app.orig_nitrile_rip = uiimage(app.NitrileRipTab);
            app.orig_nitrile_rip.ImageClickedFcn = createCallbackFcn(app, @orig_nitrile_ripImageClicked, true);
            app.SelectImageButton_8 = uibutton(app.NitrileRipTab, 'push');
            app.SelectImageButton_8.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_8Pushed, true);
            app.SelectImageButton_8.Text = 'Select Image';
            app.ShowRipDefectButton_3 = uibutton(app.NitrileRipTab, 'push');
            app.ShowRipDefectButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowRipDefectButton_3Pushed, true);
            app.ShowRipDefectButton_3.Text = 'Show Defect';
            app.DefaultOptimalThresholdButton_8 = uibutton(app.NitrileRipTab, 'push');
            app.DefaultOptimalThresholdButton_8.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_8Pushed, true);
            app.DefaultOptimalThresholdButton_8.Text = 'Default Optimal Threshold';
            app.OriginalImageLabel_8 = uilabel(app.NitrileRipTab);
            app.OriginalImageLabel_8.Text = 'Original Image';
            app.ThresholdSlider_8Label = uilabel(app.NitrileRipTab);
            app.ThresholdSlider_8Label.Text = 'Threshold Slider';
            app.ThresholdSlider_8 = uislider(app.NitrileRipTab);
            app.ThresholdSlider_8.Limits = [0 1];
            app.ThresholdSlider_8.ValueChangedFcn = createCallbackFcn(app, @ThresholdSlider_8ValueChanged, true);
            app.CurrentthresholdvalueEditField_7Label = uilabel(app.NitrileRipTab);
            app.CurrentthresholdvalueEditField_7Label.Text = 'Current threshold value';
            app.CurrentthresholdvalueEditField_7 = uieditfield(app.NitrileRipTab, 'text');
            app.NitrileStainTab = uitab(app.TabGroup);
            app.NitrileStainTab.SizeChangedFcn = createCallbackFcn(app, @NitrileStainTabSizeChanged, true);
            app.NitrileStainTab.Title = 'Nitrile Stain';
            app.stain_threshold_axes = uiaxes(app.NitrileStainTab);
            title(app.stain_threshold_axes, 'Threshold Image')
            app.nitrile_stain_detected_axes = uiaxes(app.NitrileStainTab);
            title(app.nitrile_stain_detected_axes, 'Hole Detected')
            app.NitrileGlovesStainDetectionLabel = uilabel(app.NitrileStainTab);
            app.NitrileGlovesStainDetectionLabel.Text = 'Nitrile Gloves Stain Detection';
            app.orig_nitrile_stain_img = uiimage(app.NitrileStainTab);
            app.SelectImageButton_9 = uibutton(app.NitrileStainTab, 'push');
            app.SelectImageButton_9.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_9Pushed, true);
            app.SelectImageButton_9.Text = 'Select Image';
            app.ShowNitrileStainDefectButton_4 = uibutton(app.NitrileStainTab, 'push');
            app.ShowNitrileStainDefectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ShowNitrileStainDefectButton_4Pushed, true);
            app.ShowNitrileStainDefectButton_4.Text = 'Show Defect';
            app.DefaultOptimalThresholdButton_9 = uibutton(app.NitrileStainTab, 'push');
            app.DefaultOptimalThresholdButton_9.ButtonPushedFcn = createCallbackFcn(app, @DefaultOptimalThresholdButton_9Pushed, true);
            app.DefaultOptimalThresholdButton_9.Text = 'Default Optimal Threshold';
            app.OriginalImageLabel_9 = uilabel(app.NitrileStainTab);
            app.OriginalImageLabel_9.Text = 'Original Image';
            app.HUElowerSliderLabel = uilabel(app.NitrileStainTab);
            app.HUElowerSliderLabel.Text = 'HUE lower';
            app.HUElowerSlider = uislider(app.NitrileStainTab);
            app.HUElowerSlider.Limits = [0 1];
            app.HUElowerSlider.ValueChangedFcn = createCallbackFcn(app, @HUElowerSliderValueChanged, true);
            app.HUEupperSliderLabel = uilabel(app.NitrileStainTab);
            app.HUEupperSliderLabel.Text = 'HUE upper';
            app.HUEupperSlider = uislider(app.NitrileStainTab);
            app.HUEupperSlider.Limits = [0 1];
            app.HUEupperSlider.ValueChangedFcn = createCallbackFcn(app, @HUEupperSliderValueChanged, true);
            app.BackButton_12 = uibutton(app.NitrileStainTab, 'push');
            app.BackButton_12.ButtonPushedFcn = createCallbackFcn(app, @BackButton_12Pushed, true);
            app.BackButton_12.Text = 'Back ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GloveDefectDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end